classdef Staff_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout7            matlab.ui.container.GridLayout
        SquareRampLabel        matlab.ui.control.Label
        DutyCycleLabel         matlab.ui.control.Label
        PhaseShiftLabel        matlab.ui.control.Label
        DCOffsetLabel          matlab.ui.control.Label
        FrequencyLabel         matlab.ui.control.Label
        WaveformLabel          matlab.ui.control.Label
        OutputTypeLabel        matlab.ui.control.Label
        CurrentAmplitudeLabel  matlab.ui.control.Label
        SourceIDLabel          matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Log                    matlab.ui.control.ListBox
        GridLayout6            matlab.ui.container.GridLayout
        GridLayout3            matlab.ui.container.GridLayout
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        CONNECTTOUART_Button   matlab.ui.control.Button
        COM_Lamp               matlab.ui.control.Lamp
        SeriallistDropDown     matlab.ui.control.DropDown
    end

    properties (Access = private)
        mujSeriak % Serial connection
        connected = false;
        pauzicka = 1e-3;
        connected_mosezes = false(1, 250); % Track connected devices
        lastActiveTime = zeros(1, 250); % Last message time for each device
        zdrojRowCounter = 1;
        updateTimer timer;
        inactivityThreshold = 15; % Seconds before considering device inactive
    end

    % % Generate 30 random device IDs between 1-255
    % deviceIDs = randperm(255, 15);  % 30 unique random IDs from 1-255
    %
    % % Send messages with 1-second pauses
    % for i = 1:15
    %     % Randomly choose between HB and ID message formats
    %     if rand() > 0.5
    %         % HB format (254HB<ID>)
    %         msg = sprintf('254HB%d', deviceIDs(i));
    %     else
    %         % ID format (<ID>IDxx)
    %         msg = sprintf('%dIDxx', deviceIDs(i));
    %     end
    %
    %     % Send the simulated message
    %     app.simulateMessage(msg);
    %
    %     % Pause for 1 second
    %     pause(1);
    % end

    methods (Access = public)

        function AddToTextArea(app,message)
            %             app.Log.Items
            app.Log.Items(length(app.Log.Items)+1) = {message};
            scroll(app.Log, 'bottom');
        end

        function simulateMessage(app, message)
            % Directly call serialRx with the message as the event parameter
            app.serialRx([], message);
            app.AddToTextArea(sprintf('[SIM] %s', message));
        end

        function serialRx(app, src, event)
            % Handle both real serial and simulated inputs
            data = readline(app.mujSeriak); % Using readline with terminator

            % Add raw message to log
            app.AddToTextArea(sprintf('Message: %s', data));

            % Process the message to detect device IDs
            try
                % Extract timestamp if present (e.g., "[ 9487.105][debug]")
                cleanMsg = regexprep(data, '^\[.*?\]\[.*?\]\s*', '');

                % Check for Heartbeat messages (254HB<ID>)
                hbMatch = regexp(cleanMsg, '254HB(\d+)', 'tokens');
                if ~isempty(hbMatch)
                    id = str2double(hbMatch{1}{1});
                    if ~isnan(id) && id > 0 && id <= 250
                        app.handleDeviceMessage(id, true);
                        app.lastActiveTime(id) = now;
                    end
                    return;
                end

                % Check for Temperature messages (<ID>ID Temp: <value> C)
                tempMatch = regexp(cleanMsg, '(\d+)ID Temp:', 'tokens');
                if ~isempty(tempMatch)
                    id = str2double(tempMatch{1}{1});
                    if ~isnan(id) && id > 0 && id <= 250
                        app.handleDeviceMessage(id, true);
                        app.lastActiveTime(id) = now;

                        % Extract temperature value if needed
                        tempValueMatch = regexp(cleanMsg, 'Temp:\s*([\d\.]+)\s*C', 'tokens');
                        if ~isempty(tempValueMatch)
                            tempValue = str2double(tempValueMatch{1}{1});
                            % You could store/display the temperature if needed
                        end
                    end
                    return;
                end

                % Check for other ID messages (<ID>ID...)
                idMatch = regexp(cleanMsg, '^(\d+)ID', 'tokens');
                if ~isempty(idMatch)
                    id = str2double(idMatch{1}{1});
                    if ~isnan(id) && id > 0 && id <= 250
                        app.handleDeviceMessage(id, true);
                        app.lastActiveTime(id) = now;
                    end
                    return;
                end

                % Check for voltage messages (could be associated with a device)
                if contains(cleanMsg, 'Voltage')
                    % You might want to associate this with a specific device
                    % For now just log it
                    return;
                end

            catch ME
                app.AddToTextArea(sprintf('Error processing message: %s', ME.message));
            end
        end


        function handleDeviceMessage(app, id, isConnected)
            % Update connection status
            if isConnected
                if ~app.connected_mosezes(id)
                    app.connected_mosezes(id) = true;
                    % app.pocetZobrazenychZdroju = app.pocetZobrazenychZdroju + 1;
                    % Immediately add UI controls for this device
                    app.addNewMoSeZ(id);
                    app.AddToTextArea(sprintf('MoSeZ device %d connected', id));
                else
                    % Just update the connection status for existing device
                    app.connected_mosezes(id) = true;
                end
            else
                if app.connected_mosezes(id)
                    app.connected_mosezes(id) = false;
                    % app.pocetZobrazenychZdroju = app.pocetZobrazenychZdroju - 1;
                    % Immediately remove UI controls for this device
                    app.deleteMoSeZRow(id);
                    app.AddToTextArea(sprintf('MoSeZ device %d disconnected', id));
                end
            end
        end

        function setupUpdateTimer(app)
            % Stop existing timer if it exists
            if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                stop(app.updateTimer);
                delete(app.updateTimer);
            end

            % Create new timer
            app.updateTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 5, ...
                'TimerFcn', @(~,~)app.updateSourceRows(), ...  % Changed from safeUpdateSourceRows
                'Name', 'MoSeZUpdateTimer');

            start(app.updateTimer);
            app.AddToTextArea('Started periodic MoSeZ status updates');
        end
        %
        % function updateSourceRows(app)
        %     currentTime = now;
        %     inactiveDevices = [];
        %     % Check all known devices
        %     for id = find(app.connected_mosezes)
        %         timeSinceLastActive = (currentTime - app.lastActiveTime(id)) * 24*60*60; % Convert to seconds
        %
        %         if timeSinceLastActive > app.inactivityThreshold
        %             inactiveDevices = [inactiveDevices, id];
        %             app.AddToTextArea(sprintf('MoSeZ %d inactive for %.1f seconds', id, timeSinceLastActive));
        %         end
        %     end
        %
        %     % Remove inactive devices
        %     for id = inactiveDevices
        %         app.deleteMoSeZRow(id);
        %         app.connected_mosezes(id) = false;
        %         app.AddToTextArea(sprintf('- Removed inactive MoSeZ %d', id));
        %     end
        %
        %     drawnow;
        % end

        function updateSourceRows(app)
            currentTime = datetime('now');

            % Check all known devices
            for id = find(app.connected_mosezes)
                if ~isnan(app.lastActiveTime(id))
                    timeSinceLastActive = seconds(currentTime - datetime(app.lastActiveTime(id), 'ConvertFrom', 'datenum'));

                    if timeSinceLastActive > app.inactivityThreshold
                        app.deleteMoSeZRow(id);
                        app.connected_mosezes(id) = false;
                        app.AddToTextArea(sprintf('- Removed inactive MoSeZ %d (inactive for %.1f seconds)', id, timeSinceLastActive));
                    end
                end
            end
            drawnow;
        end


        function deleteMoSeZRow(app, id)
            % Find all components for this MoSeZ
            children = app.GridLayout6.Children;
            toDelete = [];

            for i = 1:length(children)
                if ~isempty(children(i).Tag) && endsWith(children(i).Tag, num2str(id))
                    toDelete = [toDelete, children(i)]; %#ok<AGROW>
                end
            end

            % Delete the components
            for i = 1:length(toDelete)
                delete(toDelete(i));
            end

            % Compact the grid layout
            app.compactGridLayout();
            app.updateGridRowHeights();
        end


        function addNewMoSeZ(app, id)
            % Check if this MoSeZ already exists
            children = app.GridLayout6.Children;
            for i = 1:length(children)
                if ~isempty(children(i).Tag) && endsWith(children(i).Tag, num2str(id))
                    return; % Already exists, don't add again
                end
            end

            row = app.zdrojRowCounter;

            % 1. MoSeZ Toggle Button
            toggleBtn = uibutton(app.GridLayout6, 'state');
            toggleBtn.Text = sprintf('MoSeZ %d', id);
            toggleBtn.FontSize = 14;
            toggleBtn.FontWeight = 'bold';
            toggleBtn.ValueChangedFcn = @(src,event) app.toggleMoSeZ(id, src.Value);
            toggleBtn.Layout.Row = row;
            toggleBtn.Layout.Column = 1;
            toggleBtn.Tag = sprintf('MoSeZ_Toggle_%d', id);
            toggleBtn.BackgroundColor ='0.64,0.42,0.42';

            % 2. Current Spinner (Amplitude)
            ampSpinner = uispinner(app.GridLayout6);
            ampSpinner.Step = 0.1;
            ampSpinner.Limits = [-10 10];
            ampSpinner.ValueDisplayFormat = '%3.1f A';
            ampSpinner.ValueChangedFcn = @(src,event) app.setCurrent(id, event.Value);
            ampSpinner.Tag = sprintf('MoSeZ_Amplitude_%d', id);
            ampSpinner.FontSize = 14;
            ampSpinner.Layout.Row = row;
            ampSpinner.Layout.Column = 2;

            % 3. DC/AC Button Group
            dcacGrid = uigridlayout(app.GridLayout6, [2,1]);
            dcacGrid.Layout.Row = row;
            dcacGrid.Padding = [1,1,1,1];

            dcacGrid.Layout.Column = 3;
            dcacGrid.Tag = sprintf('MoSeZ_DCAC_Grid_%d', id);

            dcBtn = uibutton(dcacGrid, 'state');
            dcBtn.Text = 'DC';
            dcBtn.FontSize = 14;
            dcBtn.HorizontalAlignment = 'center'; % Align text horizontally to the center
            dcBtn.VerticalAlignment = 'center';   % Align text vertically to the center
            dcBtn.ValueChangedFcn = @(src,event) app.zpracujDCAC(id, true, src);
            dcBtn.Tag = sprintf('MoSeZ_DC_%d', id);
            dcBtn.Layout.Row = 1;
            dcBtn.Layout.Column = 1;
            dcBtn.Value = true;

            acBtn = uibutton(dcacGrid, 'state');
            acBtn.Text = 'AC';
            acBtn.FontSize = 14;
            acBtn.HorizontalAlignment = 'center'; % Align text horizontally to the center
            acBtn.VerticalAlignment = 'center';   % Align text vertically to the center
            acBtn.ValueChangedFcn = @(src,event) app.zpracujDCAC(id, false, src);
            acBtn.Tag = sprintf('MoSeZ_AC_%d', id);
            acBtn.Layout.Row = 2;
            acBtn.Layout.Column = 1;

            % 4. Waveform Button Group
            waveGrid = uigridlayout(app.GridLayout6, [3,1]);
            waveGrid.Layout.Row = row;
            waveGrid.Layout.Column = 4;
            waveGrid.Padding = [1,1,1,1];
            waveGrid.Tag = sprintf('MoSeZ_Wave_Grid_%d', id);

            sinBtn = uibutton(waveGrid, 'state');
            sinBtn.Text = 'SIN';
            sinBtn.Value = true;
            sinBtn.FontSize = 14;
            sinBtn.ValueChangedFcn = @(src,event) app.zpracujVlnu(id, 'SIN', src);
            sinBtn.Tag = sprintf('MoSeZ_SIN_%d', id);
            sinBtn.Layout.Row = 1;
            sinBtn.Layout.Column = 1;
            sinBtn.Enable = 'off';

            squareBtn = uibutton(waveGrid, 'state');
            squareBtn.Text = 'SQUARE';
            squareBtn.FontSize = 14;
            squareBtn.ValueChangedFcn = @(src,event) app.zpracujVlnu(id, 'SQUARE', src);
            squareBtn.Tag = sprintf('MoSeZ_SQUARE_%d', id);
            squareBtn.Layout.Row = 2;
            squareBtn.Layout.Column = 1;
            squareBtn.Enable = 'off';

            triangleBtn = uibutton(waveGrid, 'state');
            triangleBtn.Text = 'TRIANGLE';
            triangleBtn.FontSize = 14;
            triangleBtn.ValueChangedFcn = @(src,event) app.zpracujVlnu(id, 'TRIANGLE', src);
            triangleBtn.Tag = sprintf('MoSeZ_TRIANGLE_%d', id);
            triangleBtn.Layout.Row = 3;
            triangleBtn.Layout.Column = 1;
            triangleBtn.Enable = 'off';

            % 5. Frequency Spinner
            freqSpinner = uispinner(app.GridLayout6);
            freqSpinner.Limits = [1 1200];
            freqSpinner.ValueDisplayFormat = '%d Hz';
            freqSpinner.ValueChangedFcn = @(src,event) app.setFrequency(id, event.Value);
            freqSpinner.Tag = sprintf('MoSeZ_Freq_%d', id);
            freqSpinner.FontSize = 14;
            freqSpinner.Enable = 'off';
            freqSpinner.Layout.Row = row;
            freqSpinner.Layout.Column = 5;
            freqSpinner.Value = 1;

            % 6. Offset Spinner
            offsetSpinner = uispinner(app.GridLayout6);
            offsetSpinner.Step = 0.1;
            offsetSpinner.Limits = [-10 10];
            offsetSpinner.ValueDisplayFormat = '%3.1f A';
            offsetSpinner.ValueChangedFcn = @(src,event) app.setOffset(id, event.Value);
            offsetSpinner.Tag = sprintf('MoSeZ_Offset_%d', id);
            offsetSpinner.FontSize = 14;
            offsetSpinner.Enable = 'off';
            offsetSpinner.Layout.Row = row;
            offsetSpinner.Layout.Column = 6;
            offsetSpinner.Value = 0;

            % 7. Phase Spinner
            phaseSpinner = uispinner(app.GridLayout6);
            phaseSpinner.Limits = [0 360];
            phaseSpinner.ValueDisplayFormat = '%d °';
            phaseSpinner.ValueChangedFcn = @(src,event) app.setPhase(id, event.Value);
            phaseSpinner.Tag = sprintf('MoSeZ_Phase_%d', id);
            phaseSpinner.FontSize = 14;
            phaseSpinner.Enable = 'off';
            phaseSpinner.Layout.Row = row;
            phaseSpinner.Layout.Column = 7;
            phaseSpinner.Value = 0;

            % 8. Duty Cycle Spinner
            dutySpinner = uispinner(app.GridLayout6);
            dutySpinner.Limits = [1 99];
            dutySpinner.ValueDisplayFormat = '%d%%';
            dutySpinner.ValueChangedFcn = @(src,event) app.setDutyCycle(id, event.Value);
            dutySpinner.Tag = sprintf('MoSeZ_Duty_%d', id);
            dutySpinner.FontSize = 14;
            dutySpinner.Enable = 'off';
            dutySpinner.Layout.Row = row;
            dutySpinner.Layout.Column = 8;
            dutySpinner.Value = 50;

            % 9. Square Ramp Spinner
            rampSpinner = uispinner(app.GridLayout6);
            rampSpinner.Limits = [0 50];
            rampSpinner.ValueDisplayFormat = '%d%%';
            rampSpinner.ValueChangedFcn = @(src,event) app.setSquareRamp(id, event.Value);
            rampSpinner.Tag = sprintf('MoSeZ_Ramp_%d', id);
            rampSpinner.FontSize = 14;
            rampSpinner.Enable = 'off';
            rampSpinner.Layout.Row = row;
            rampSpinner.Layout.Column = 9;
            rampSpinner.Value = 0;

            freqSpinner.ValueChangedFcn = @(src,event) app.setFrequency(id, event.Value);
            offsetSpinner.ValueChangedFcn = @(src,event) app.setOffset(id, event.Value);
            phaseSpinner.ValueChangedFcn = @(src,event) app.setPhase(id, event.Value);
            dutySpinner.ValueChangedFcn = @(src,event) app.setDutyCycle(id, event.Value);
            rampSpinner.ValueChangedFcn = @(src,event) app.setSquareRamp(id, event.Value);

            % Update row counter
            app.zdrojRowCounter = app.zdrojRowCounter + 1;
            % app.GridLayout6.RowHeight = repmat({'1x'}, 1, app.zdrojRowCounter);
            app.AddToTextArea(sprintf('Added controls for MoSeZ %d', id));
            app.sortSourceRows();
            app.updateGridRowHeights();
        end


        function updateGridRowHeights(app)
            % Calculate needed rows (header + devices)
            neededRows = 1 + sum(app.connected_mosezes);

            % Ensure we have at least 10 rows for proper scrolling
            totalRows = max(10, neededRows);

            % Set row heights (first row is header, rest are 100px)
            heights = [{'fit'}, repmat({100}, 1, totalRows-1)];
            app.GridLayout6.RowHeight = heights;
        end
        function sortSourceRows(app)
            % Get all children of the grid layout
            children = app.GridLayout6.Children;

            % Extract all MoSeZ rows and their IDs
            sourceData = struct('id', {}, 'components', {}, 'row', {});

            % Find all components that belong to MoSeZ rows (excluding header)
            for i = 1:length(children)
                if ~isempty(children(i).Tag) && contains(children(i).Tag, 'MoSeZ_')
                    % Extract ID from tag (format is 'MoSeZ_Type_ID')
                    tagParts = split(children(i).Tag, '_');
                    id = str2double(tagParts{end});

                    % Find if we already have this source
                    found = false;
                    for j = 1:length(sourceData)
                        if sourceData(j).id == id
                            sourceData(j).components = [sourceData(j).components; children(i)];
                            found = true;
                            break;
                        end
                    end

                    % If not found, create new entry
                    if ~found
                        newSource.id = id;
                        newSource.components = children(i);
                        newSource.row = children(i).Layout.Row;
                        sourceData = [sourceData; newSource];
                    end
                end
            end

            % Sort by ID
            [~, sortIdx] = sort([sourceData.id]);
            sourceData = sourceData(sortIdx);

            % Reassign rows (start from row 2 since row 1 is header)
            newRow = 2;
            for i = 1:length(sourceData)
                for j = 1:length(sourceData(i).components)
                    sourceData(i).components(j).Layout.Row = newRow;
                end
                newRow = newRow + 1;
            end

            % Update row counter
            app.zdrojRowCounter = newRow;

            % Adjust grid height if needed
            app.GridLayout6.RowHeight = [{'fit'}, repmat({'1x'}, 1, newRow-2)];
        end
        function compactGridLayout(app)
            % First sort all rows
            app.sortSourceRows();

            % Then adjust the grid height as before
            newRowHeights = repmat({'1x'}, 1, app.zdrojRowCounter-1);
            app.GridLayout6.RowHeight = newRowHeights;
        end

        % function compactGridLayout(app)
        %            % Reorganize rows after deletion
        %            children = app.GridLayout6.Children;
        %            currentRow = 1; % Start after header row
        %
        %            % Group components by their original row
        %            rowMap = containers.Map('KeyType', 'double', 'ValueType', 'any');
        %            for i = 1:length(children)
        %                if ~isempty(children(i).Layout.Row)
        %                    row = children(i).Layout.Row;
        %                    if ~isKey(rowMap, row)
        %                        rowMap(row) = [];
        %                    end
        %                    rowMap(row) = [rowMap(row), children(i)];
        %                end
        %            end
        %
        %            % Reassign rows sequentially
        %            rowNumbers = sort(cell2mat(keys(rowMap)));
        %            for row = rowNumbers
        %                components = rowMap(row);
        %                for j = 1:length(components)
        %                    components(j).Layout.Row = currentRow;
        %                end
        %                if row > 1 % Don't count header
        %                    currentRow = currentRow + 1;
        %                end
        %            end
        %
        %            % Update row counter
        %            app.zdrojRowCounter = currentRow;
        %
        %            % Adjust grid height
        %            newRowHeights = repmat({'1x'}, 1, currentRow-1);
        %            app.GridLayout6.RowHeight = newRowHeights;
        %        end


        % function setCurrent(app, channel, valueAmps)
        %     try
        %         textToSend = sprintf('%dI%d', channel, int32(valueAmps*1000));
        %         log = sprintf(['Požadavek n' ...
        %             'a %.2f A odeslán na zdroj s ID %d'], valueAmps, channel);
        %         pause(app.pauzicka);
        %         writeline(app.mujSeriak,textToSend);
        %         app.AddToTextArea(sprintf('Set MoSeZ %d to %.2f A', channel, valueAmps));
        %     catch ME
        %         app.AddToTextArea(sprintf('Failed to set MoSeZ %d current: %s', channel, ME.message));
        %     end
        %     pause(app.pauzicka);
        %
        % end

        function setCurrent(app, channel, valueAmps)
            textToSend = sprintf('%dI%d', channel, int32(valueAmps*1000));
            log = sprintf('Požadavek na %.2f A odeslán na zdroj s ID %d', valueAmps, channel);

            %.. send
            app.AddToTextArea(log);
            writeline(app.mujSeriak,textToSend);
            pause(app.pauzicka)
        end


        function zpracujDCAC(app, id, isDC, src)

            % Get both buttons for this MoSeZ
            dcBtn = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_DC_%d', id));
            acBtn = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_AC_%d', id));

            % Ensure mutual exclusion
            if isDC
                dcBtn.Value = true;
                acBtn.Value = false;
            else
                dcBtn.Value = false;
                acBtn.Value = true;
            end

            % Get all components for this MoSeZ

            stateButton = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Toggle_%d', id));
            waveGrid = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Wave_Grid_%d', id));
            freqSpinner = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Freq_%d', id));
            offsetSpinner = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Offset_%d', id));
            phaseSpinner = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Phase_%d', id));
            dutySpinner = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Duty_%d', id));
            rampSpinner = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Ramp_%d', id));

            if isDC
                % DC mode - disable AC controls
                set([waveGrid.Children; freqSpinner; offsetSpinner; phaseSpinner; dutySpinner; rampSpinner], 'Enable', 'off');
                src.Value = true;
                app.AddToTextArea(sprintf('MoSeZ %d set to DC mode', id));


            else
                % AC mode - enable AC controls
                set([waveGrid.Children; freqSpinner; offsetSpinner; phaseSpinner], 'Enable', 'on');

                % Enable/disable duty and ramp based on waveform
                currentWave = findobj(waveGrid.Children, 'Value', 1);
                if ~isempty(currentWave) && strcmp(currentWave.Tag, sprintf('MoSeZ_SQUARE_%d', id))
                    set([dutySpinner; rampSpinner], 'Enable', 'on');
                else
                    set([dutySpinner; rampSpinner], 'Enable', 'off');
                end
            end

            if stateButton.Value == true
                % 5. Frequency Spinner
                app.setFrequency(id, freqSpinner.Value);

                % 6. Offset Spinner
                app.setOffset(id, offsetSpinner.Value);

                % 7. Phase Spinner
                app.setPhase(id, phaseSpinner.Value);

                % 8. Duty Cycle Spinner
                app.setDutyCycle(id, dutySpinner.Value);

                % 9. Square Ramp Spinner
                app.setSquareRamp(id, rampSpinner.Value);
                % Toggle Output
                app.toggleMoSeZ(id, src.Value);
            end
        end


        function zpracujVlnu(app, id, waveType, src)
            % Get all components for this MoSeZ
            dutySpinner = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Duty_%d', id));
            rampSpinner = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Ramp_%d', id));
            waveGrid = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Wave_Grid_%d', id));

            % Unpress other buttons
            otherBtns = setdiff(waveGrid.Children, src);
            set(otherBtns, 'Value', false);

            % Enable/disable duty and ramp for square wave
            if strcmp(waveType, 'SQUARE')
                set([dutySpinner; rampSpinner], 'Enable', 'on');
            else
                set([dutySpinner; rampSpinner], 'Enable', 'off');
            end

        end

        % Supporting methods for parameter changes
        function setFrequency(app, id, value)
            sinButton = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_SIN_%d', id));
            squareButton = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_SQUARE_%d', id));
            triangelButton = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_TRIANGLE_%d', id));
            app.AddToTextArea(sprintf('MoSeZ %d frequency set to %d Hz ', id, value));

            if sinButton.Value
                textToSend = sprintf('%dH%d', id, value);
                app.AddToTextArea(sprintf('MoSeZ AC output set to SIN', id, value));
            elseif squareButton.Value
                textToSend = sprintf('%dS%d', id, value);
                app.AddToTextArea(sprintf('MoSeZ AC output set to SQUARE', id, value));
            elseif triangelButton.Value
                textToSend = sprintf('%dT%d', id, value);
                app.AddToTextArea(sprintf('MoSeZ AC output set to TRIANGLEc', id, value));
            end

            writeline(app.mujSeriak, textToSend);
        end

        function setOffset(app, id, value)
            app.AddToTextArea(sprintf('MoSeZ %d offset set to %.2f A', id, value));
            textToSend = sprintf('%dO%d', id, int32(value*1000));
            writeline(app.mujSeriak, textToSend);
        end

        function setPhase(app, id, value)
            app.AddToTextArea(sprintf('MoSeZ %d phase set to %d°', id, value));
            textToSend = sprintf('%dF%d', id, value);
            writeline(app.mujSeriak, textToSend);
        end

        function setDutyCycle(app, id, value)
            app.AddToTextArea(sprintf('MoSeZ %d duty cycle set to %d%%', id, value));
            textToSend = sprintf('%dC%d', id, value);
            writeline(app.mujSeriak, textToSend);
        end

        function setSquareRamp(app, id, value)
            app.AddToTextArea(sprintf('MoSeZ %d square ramp set to %d%%', id, value));
            textToSend = sprintf('%dRT%d', id, value);
            writeline(app.mujSeriak, textToSend);
        end

        function toggleMoSeZ(app, id, state)

            % Get both buttons for this MoSeZ
            acBtn = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_AC_%d', id));
            toggleBtn = findobj(app.GridLayout6.Children, 'Tag', sprintf('MoSeZ_Toggle_%d', id));

            if state
                toggleBtn.BackgroundColor = '0.45,0.64,0.46';
                if acBtn.Value == true
                    textToSend = sprintf('%dA1', id);
                    app.AddToTextArea(sprintf('MoSeZ %d turned AC ON', id));
                else
                    textToSend = sprintf('%dD1', id);
                    app.AddToTextArea(sprintf('MoSeZ %d turned DC ON', id));
                end
            else
                toggleBtn.BackgroundColor ='0.64,0.42,0.42';
                if acBtn.Value == true
                    textToSend = sprintf('%dA0', id);
                    app.AddToTextArea(sprintf('MoSeZ %d turned AC OFF', id));
                else
                    textToSend = sprintf('%dD0', id);
                    app.AddToTextArea(sprintf('MoSeZ %d turned DC OFF', id));
                end
            end
            writeline(app.mujSeriak, textToSend);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Log.Items(:) = '';
            list = serialportlist;
            app.SeriallistDropDown.Items = list;
            app.GridLayout6.ColumnWidth = {'1.5x', '1x', '1x', '1x', '1x', '1x', '1x', '1x', '1x'};
            app.GridLayout6.RowHeight = repmat({100}, 1, 10); % Initial 10 rows at 100px each
            app.GridLayout6.RowSpacing = 5; % Add some spacing between rows
            app.GridLayout6.Padding = [0 0 0 0];
            app.GridLayout6.Tag = 'SourceTable';
            app.GridLayout6.Layout.Row = 5;
            app.GridLayout6.Layout.Column = 1;
            app.GridLayout6.Scrollable = 'on';
            % Initialize grid with proper row heights
            app.updateGridRowHeights();


            app.AddToTextArea('Application started');
            app.setupUpdateTimer(); % Just start the timer, no test devices

            % for i=1:1:20
            %     app.addNewMoSeZ(i);
            % end
        end

        % Button pushed function: CONNECTTOUART_Button
        function CONNECTTOUART_ButtonPushed(app, event)
            if (app.connected == false)
                COM_Name = app.SeriallistDropDown.Value;
                app.mujSeriak = serialport(COM_Name,921600,"Timeout",1000);
                configureTerminator(app.mujSeriak, "LF");
                configureCallback(app.mujSeriak,"terminator",@app.serialRx);
                % status = getpinstatus(app.mujSeriak);

                %pokud naváže spojení
                %s čímkoliv
                app.connected = true;
                app.COM_Lamp.Color = 'green';

                app.AddToTextArea('Spojení po sériovém portu navázáno');
                app.AddToTextArea('Pokud byly zdroje před navázáním spojení odpojeny od napájení, jsou nastaveny na 0 A');
                app.AddToTextArea('Jinak jsou nastaveny dle posledního požadavku z minulé relace');
                app.connected = true;

            else
                delete(app.mujSeriak);
                app.connected = false;
                app.COM_Lamp.Color = 'red';

                app.AddToTextArea('Spojení po sériovém portu zavřeno');
                app.AddToTextArea('');
                app.connected = false;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.mujSeriak);
            delete(app)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case {'o','O'}
                    app.OpenHodor();
                case {'c','C'}
                    app.CloseHodor();
                case {'t','T'}
                    app.TickHodor();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.Tag = '4';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SeriallistDropDown = uidropdown(app.GridLayout3);
            app.COM_Lamp = uilamp(app.GridLayout3);
            app.CONNECTTOUART_Button = uibutton(app.GridLayout3, 'push');
            app.CONNECTTOUART_Button.ButtonPushedFcn = createCallbackFcn(app, @CONNECTTOUART_ButtonPushed, true);
            app.CONNECTTOUART_Button.Tag = '4';
            app.CONNECTTOUART_Button.Text = 'CONNECT TO SERIAL';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cimra.svg');
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = 'ZCU.svg';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout6.Tag = 'SourceTable';
            app.Log = uilistbox(app.GridLayout);
            app.Label = uilabel(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_3 = uilabel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.SourceIDLabel = uilabel(app.GridLayout7);
            app.SourceIDLabel.Text = 'Source ID';
            app.CurrentAmplitudeLabel = uilabel(app.GridLayout7);
            app.CurrentAmplitudeLabel.Text = 'Current Amplitude';
            app.OutputTypeLabel = uilabel(app.GridLayout7);
            app.OutputTypeLabel.Text = 'Output Type';
            app.WaveformLabel = uilabel(app.GridLayout7);
            app.WaveformLabel.Text = 'Waveform';
            app.FrequencyLabel = uilabel(app.GridLayout7);
            app.FrequencyLabel.Text = 'Frequency';
            app.DCOffsetLabel = uilabel(app.GridLayout7);
            app.DCOffsetLabel.Text = 'DC Offset';
            app.PhaseShiftLabel = uilabel(app.GridLayout7);
            app.PhaseShiftLabel.Text = 'Phase Shift';
            app.DutyCycleLabel = uilabel(app.GridLayout7);
            app.DutyCycleLabel.Text = 'Duty Cycle';
            app.SquareRampLabel = uilabel(app.GridLayout7);
            app.SquareRampLabel.Text = 'Square Ramp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Staff_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end